classdef QuantumTunnellingSimulation_Minh_Nguyen_13582453 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ResetButton                    matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
        MassSlider                     matlab.ui.control.Slider
        MassSliderLabel                matlab.ui.control.Label
        PotentialofBarrierSlider       matlab.ui.control.Slider
        PotentialofBarrierSliderLabel  matlab.ui.control.Label
        WidthofBarrierSlider           matlab.ui.control.Slider
        WidthofBarrierSliderLabel      matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mass
        BarrierPotiential
        BarrierWidth
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %Function by Peter van Alem
           %GUI and modification by Minh N.
            app.mass=1;
            app.BarrierPotiential=3;
            app.BarrierWidth=1.5;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)

            cla(app.UIAxes)

            % FEM analysis of the probability distribution for the 
            % 1D position of a quantum particle. By Peter van Alem
            % From https://au.mathworks.com/matlabcentral/fileexchange/82878-1d-fem-analysis-of-quantum-tunneling-effect
            
            
            % Parameters
            l = 20;               % length of half the 1D domain  
            m = app.mass;                % mass
            h_bar = 1;            % h / (2 * pi)
            sigma = 2;            % uncertainty in the initial position of the particle
            k_0 = 2;              % k_0·h_bar: mean momentum
            x_0 = -3/5 * l;       % center of initial wave packet
            V_b = app.BarrierPotiential;            % value of potential barrier
            a = app.BarrierWidth;              % width of potential barrier
            % Potential barrier
            V = @(x) V_b * rectangularPulse(-a/2, a/2, x);
            
            % Mesh generation
            number_of_nodes = 250;
            p = linspace(-l, l, number_of_nodes);
             
            % Connectivity
            t = [1 : number_of_nodes - 1; 2 : number_of_nodes]';
            number_of_elements = size(t, 1);
            
            % Initialization of global matrices K, M, M_V
            K   = sparse(number_of_nodes, number_of_nodes);
            M   = K;
            M_V = K;
            
            % Calculation of Ke, Me, Me_V & assembly of K, M, M_V
            for element = 1 : number_of_elements
                nodes = t(element, :);
                P = [ones(1, 2); p(:, nodes)];
                C = inv(P);
                l_e = abs(det(P));
                grads_phis = C(:, 2);
                Ke = h_bar^2 / (2 * m) * grads_phis * grads_phis' * l_e;
                mean_of_phis = 1/2 * [1; 1];
                Me = mean_of_phis * mean_of_phis' * l_e;
                x_mean = mean(p(nodes));
                Me_V = mean_of_phis * V(x_mean) * mean_of_phis' * l_e;
                K(nodes, nodes) = K(nodes, nodes) + Ke;
                M(nodes, nodes) = M(nodes, nodes) + Me;
                M_V(nodes, nodes) = M_V(nodes, nodes) + Me_V;
            end
            
            % Dirichlet boundary
            Dirichlet = [1, number_of_nodes];
            K(Dirichlet, :) = 0;
            M(Dirichlet, :) = 0;
            M(Dirichlet, Dirichlet) = speye(numel(Dirichlet));
             
            % Time integration
            time = linspace(0, 10.5, 500);
            U_initial = (1 / (2 * pi * sigma^2))^(1/4) * exp(1i * k_0 * p) .* ... 
                         exp(-(p - x_0).^2 / (4 * sigma^2));
            dU_dt = @(time, U) -M \ (1i / h_bar * (K + M_V)) * U;
            [~, U_t] = ode23tb(dU_dt, time, U_initial);
            
            % Plotting
            set(gcf, 'color', 'white')
            colormap(1 - autumn)
            hold on
            h = patch(p, abs(U_t(1, :).^2), abs(U_initial).^2, 'Linewidth', 1);
            % Scaling potential barrier
            g = plot(p, V(p) / (10 * a), 'Linewidth', 2, 'Color', 'k');
            max_value = 1.25 * max(abs(U_initial).^2);
            axis([min(p), max(p), 0, max_value])
            xlabel('$x$', 'interpreter', 'latex', 'FontSize', 20)
            ylabel('$|\psi(x,t)|^2$', 'interpreter', 'latex', 'FontSize', 20)
            title(sprintf(['$V_e = ', num2str(V_b), '$']), ...
                'interpreter', 'latex', 'FontSize', 20)
            drawnow
            for i = 1 : numel(time)
                set(h, 'YData',  abs(U_t(i, :)).^2);
                set(h, 'CData', abs(U_t(i, :)).^2);
                pause(0.005)
                drawnow
            end
        end

        % Value changing function: MassSlider
        function MassSliderValueChanging(app, event)

            
        end

        % Value changed function: MassSlider
        function MassSliderValueChanged(app, event)
            value = app.MassSlider.Value;
            app.mass=value;
            
        end

        % Value changed function: PotentialofBarrierSlider
        function PotentialofBarrierSliderValueChanged(app, event)
            value = app.PotentialofBarrierSlider.Value;
            app.BarrierPotiential=value;
        end

        % Value changed function: WidthofBarrierSlider
        function WidthofBarrierSliderValueChanged(app, event)
            value = app.WidthofBarrierSlider.Value;
            app.BarrierWidth=value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.mass=1;
            app.BarrierPotiential=3;
            app.BarrierWidth=1.5;
%             cla(app.UIAxes)
            app.MassSlider.Value=1;
            app.PotentialofBarrierSlider.Value=3;
            app.WidthofBarrierSlider.Value=1.5;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Quantum Tunnelling Simulation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.WidthofBarrierSliderLabel = uilabel(app.GridLayout);
            app.WidthofBarrierSliderLabel.Text = 'Width of Barrier';
            app.WidthofBarrierSlider = uislider(app.GridLayout);
            app.WidthofBarrierSlider.Limits = [1.5 5];
            app.WidthofBarrierSlider.ValueChangedFcn = createCallbackFcn(app, @WidthofBarrierSliderValueChanged, true);
            app.WidthofBarrierSlider.Value = 1.5;
            app.PotentialofBarrierSliderLabel = uilabel(app.GridLayout);
            app.PotentialofBarrierSliderLabel.Text = 'Potential of Barrier';
            app.PotentialofBarrierSlider = uislider(app.GridLayout);
            app.PotentialofBarrierSlider.Limits = [1 5];
            app.PotentialofBarrierSlider.ValueChangedFcn = createCallbackFcn(app, @PotentialofBarrierSliderValueChanged, true);
            app.PotentialofBarrierSlider.Value = 3;
            app.MassSliderLabel = uilabel(app.GridLayout);
            app.MassSliderLabel.Text = 'Mass';
            app.MassSlider = uislider(app.GridLayout);
            app.MassSlider.Limits = [0.5 2];
            app.MassSlider.ValueChangedFcn = createCallbackFcn(app, @MassSliderValueChanged, true);
            app.MassSlider.ValueChangingFcn = createCallbackFcn(app, @MassSliderValueChanging, true);
            app.MassSlider.Value = 1;
            app.PlayButton = uibutton(app.GridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuantumTunnellingSimulation_Minh_Nguyen_13582453

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end